classdef ClassifyCosmeticsApp_UX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BeyondtheLabelUIFigure        matlab.ui.Figure
        ParsedIngredientsPanel        matlab.ui.container.Panel
        ResultIcon                    matlab.ui.control.Image
        Instruction_4                 matlab.ui.control.Label
        TryanotherimageButton         matlab.ui.control.Button
        ParsedIngredientsLabel        matlab.ui.control.Label
        ResultLabel                   matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        AnalyzeButton                 matlab.ui.control.Button
        RegionofInterestPanel         matlab.ui.container.Panel
        Instruction_3                 matlab.ui.control.Label
        ExtractTextButton             matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        RawImagePanel                 matlab.ui.container.Panel
        Instruction2                  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        SelectRegionofInterestButton  matlab.ui.control.Button
        Instruction_1                 matlab.ui.control.Label
        ImageFileButton               matlab.ui.control.Button
        ImageFileDropDown             matlab.ui.control.DropDown
        UIAxes                        matlab.ui.control.UIAxes
        LoadingLabel                  matlab.ui.control.Label
    end

    
    properties (Access = public)
        ROI
        img
        roi_img
        folder
        onePanelWidth = 576;
        twoPanelWidth = 768;
        GridLayout                    matlab.ui.container.GridLayout
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.BeyondtheLabelUIFigure,"center")
        end

        % Button pushed function: ImageFileButton
        function ImageFileButtonButtonPushed(app, event)
            
            [filename, app.folder] =  uigetfile({'*.png;*.jpg;*.jpeg',...
                'Image Files (*.png, *.jpg, *.jpeg)'; '*.*', 'All Files (*.*)'},...
                'Select an image',...
                fullfile(pwd));
            if ~ischar(filename); return; end   %user cancel
            focus(app.BeyondtheLabelUIFigure)
            app.LoadingLabel.Visible = "on";
            drawnow
            [~,~,ext] = fileparts(filename);
            files = dir(fullfile(app.folder,['*' ext]));
            app.ImageFileDropDown.Items = {files.name};
            app.ImageFileDropDown.Value = filename;
            app.ImageFileDropDown.Enable = 'on';

            app.img = imread(fullfile(app.folder,filename));
            imshow(app.img,'Parent',app.UIAxes)

            app.SelectRegionofInterestButton.Enable = "on";
            
            app.LoadingLabel.Text = "Image has been loaded.";
        end

        % Button pushed function: SelectRegionofInterestButton
        function SelectRegionofInterestButtonPushed(app, event)
            if app.SelectRegionofInterestButton.Text == "Select Region of Interest"
                app.SelectRegionofInterestButton.Text = "Set Region of Interest";
                if isempty(app.ROI)
                    app.ROI = drawrectangle(app.UIAxes,"Color",'red');
                    app.ROI.UserData = app.img;
                    addlistener(app.ROI,'MovingROI',@ROIevents);
                    addlistener(app.ROI,'ROIMoved',@ROIevents);
                else
                    app.ROI.InteractionsAllowed = 'all';
                    app.ROI.FaceSelectable = 1;
                end
               

            else
                app.SelectRegionofInterestButton.Text = "Select Region of Interest";
                app.ROI.InteractionsAllowed='none';
                app.ROI.FaceSelectable=0;
                
                app.roi_img = imcrop(app.img, app.ROI.Position);
                if (isempty(app.roi_img))
                    app.roi_img = app.img;
                end
                imshow(app.roi_img,'Parent',app.UIAxes2);
                title(app.UIAxes2,'Region of Interest (ROI)');
                % fprintf('ROI Position: (x: %d, y: %d), Width: %d, Height: %d\n', app.ROI.Position);
                app.ExtractTextButton.Enable = "on";

                app.RegionofInterestPanel.Visible = "on";
                app.RawImagePanel.Visible = "off";

                app.LoadingLabel.Text = "Image has been updated.";

            end
        end

        % Button pushed function: ExtractTextButton
        function ExtractTextButtonPushed(app, event)
            app.LoadingLabel.Text = "Extracting...";
            drawnow
            
            app.TextArea.Value = '';

            ocrResults = ocr(app.roi_img);
            app.TextArea.Value = strtrim(ocrResults.Text);

            if ~isempty(ocrResults.Text)
                app.AnalyzeButton.Enable = 'on';
            else
                % Disable the classify button
                app.AnalyzeButton.Enable = 'off';
            end

            app.ParsedIngredientsPanel.Visible = "on";
            app.LoadingLabel.Text = "Extracted.";
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            app.LoadingLabel.Text = "Analyzing...";
            drawnow

            
            load("prediction_data.mat","bagOfWords","simpleTextClassifierMdl")
            Ingredients = strtrim(app.TextArea.Value);
            % Remove empty entries
            Ingredients = Ingredients(~cellfun('isempty', Ingredients));
            % Merge into one string
            Ingredients = strjoin(Ingredients, ' ');

            documentsNew = preprocessText(Ingredients);
            XNew = encode(bagOfWords,documentsNew);
            labelsNew = predict(simpleTextClassifierMdl,XNew);

            if labelsNew == "Clean"
                app.ResultIcon.Visible = "on";
                app.ResultIcon.ImageSource = "assets/validated.png";
                app.ResultLabel.Text = 'This product is clean!';
            else
                app.ResultIcon.Visible = "on";
                app.ResultIcon.ImageSource = "assets/errorCross.png";
                app.ResultLabel.Text = 'This product contains toxic ingredients';
            end
            app.ResultLabel.Visible = "on";

            app.LoadingLabel.Text = "Done";


        end

        % Value changed function: ImageFileDropDown
        function ImageFileDropDownValueChanged(app, event)
           
            cla(app.UIAxes)
            app.SelectRegionofInterestButton.Text = "Select Region of Interest";
            app.ROI = [];
            app.img = imread(fullfile(app.folder,app.ImageFileDropDown.Value));
            imshow(app.img,'Parent',app.UIAxes)
            cla(app.UIAxes2)
        end

        % Button pushed function: TryanotherimageButton
        function TryanotherimageButtonPushed(app, event)
            app.ParsedIngredientsPanel.Visible = "off";
            app.RawImagePanel.Visible = "on";
            app.RegionofInterestPanel.Visible = "off";
            app.ResultIcon.Visible = "off";
            app.ResultLabel.Visible = "off";
            app.LoadingLabel.Text = "";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BeyondtheLabelUIFigure = uifigure('Visible', 'off');
            app.BeyondtheLabelUIFigure.Name = 'Beyond the Label';
            app.LoadingLabel = uilabel(app.BeyondtheLabelUIFigure);
            app.LoadingLabel.Visible = 'off';
            app.LoadingLabel.Text = 'Loading...';
            app.RawImagePanel = uipanel(app.BeyondtheLabelUIFigure);
            app.RawImagePanel.Title = 'Raw Image';
            app.UIAxes = uiaxes(app.RawImagePanel);
            title(app.UIAxes, 'Raw Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ImageFileDropDown = uidropdown(app.RawImagePanel);
            app.ImageFileDropDown.Items = {'Select File...'};
            app.ImageFileDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageFileDropDownValueChanged, true);
            app.ImageFileDropDown.Enable = 'off';
            app.ImageFileDropDown.Value = 'Select File...';
            app.ImageFileButton = uibutton(app.RawImagePanel, 'push');
            app.ImageFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImageFileButtonButtonPushed, true);
            app.ImageFileButton.Icon = 'file_open.png';
            app.ImageFileButton.Tooltip = {'Load EEG file (EDF format)'};
            app.ImageFileButton.Text = 'Browse';
            app.Instruction_1 = uilabel(app.RawImagePanel);
            app.Instruction_1.Text = '1. Upload the file';
            app.SelectRegionofInterestButton = uibutton(app.RawImagePanel, 'push');
            app.SelectRegionofInterestButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRegionofInterestButtonPushed, true);
            app.SelectRegionofInterestButton.Enable = 'off';
            app.SelectRegionofInterestButton.Text = 'Select Region of Interest';
            app.Label = uilabel(app.RawImagePanel);
            app.Label.Text = 'This app will allow the user to parse text from pictures of ingredient lists to allow the user to determine whether or not the product is clean. ';
            app.Instruction2 = uilabel(app.RawImagePanel);
            app.Instruction2.Text = '2. Click the button below to begin selecting the Region of Interest (ROI) by dragging your cursor over the image. Click the button again to set it.';
            app.RegionofInterestPanel = uipanel(app.BeyondtheLabelUIFigure);
            app.RegionofInterestPanel.Title = 'Region of Interest';
            app.RegionofInterestPanel.Visible = 'off';
            app.UIAxes2 = uiaxes(app.RegionofInterestPanel);
            title(app.UIAxes2, 'Region of Interest')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.ExtractTextButton = uibutton(app.RegionofInterestPanel, 'push');
            app.ExtractTextButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractTextButtonPushed, true);
            app.ExtractTextButton.Enable = 'off';
            app.ExtractTextButton.Text = 'Extract Text';
            app.Instruction_3 = uilabel(app.RegionofInterestPanel);
            app.Instruction_3.Text = '3. Extract the text from the image below.';
            app.ParsedIngredientsPanel = uipanel(app.BeyondtheLabelUIFigure);
            app.ParsedIngredientsPanel.Title = 'Parsed Ingredients';
            app.ParsedIngredientsPanel.Visible = 'off';
            app.AnalyzeButton = uibutton(app.ParsedIngredientsPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Enable = 'off';
            app.AnalyzeButton.Text = 'Analyze';
            app.TextArea = uitextarea(app.ParsedIngredientsPanel);
            app.ResultLabel = uilabel(app.ParsedIngredientsPanel);
            app.ResultLabel.Visible = 'off';
            app.ResultLabel.Text = 'Result';
            app.ParsedIngredientsLabel = uilabel(app.ParsedIngredientsPanel);
            app.ParsedIngredientsLabel.Text = 'Parsed Ingredients';
            app.TryanotherimageButton = uibutton(app.ParsedIngredientsPanel, 'push');
            app.TryanotherimageButton.ButtonPushedFcn = createCallbackFcn(app, @TryanotherimageButtonPushed, true);
            app.TryanotherimageButton.Text = 'Try another image';
            app.Instruction_4 = uilabel(app.ParsedIngredientsPanel);
            app.Instruction_4.Text = '4. Analyze the text to determine whether or not the ingredients are clean. ';
            app.ResultIcon = uiimage(app.ParsedIngredientsPanel);
            app.ResultIcon.Visible = 'off';
            % Show the figure after all components are created
            app.BeyondtheLabelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClassifyCosmeticsApp_UX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BeyondtheLabelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BeyondtheLabelUIFigure)
        end
    end
end